classdef CollectTrainingData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CollectTrainingDataFigure  matlab.ui.Figure
        EMGAxes                    matlab.ui.control.UIAxes
        MyoLamp                    matlab.ui.control.Lamp
        RecordingProtocolPanel     matlab.ui.container.Panel
        CalibrateMVCButton         matlab.ui.control.StateButton
        MVCEditField               matlab.ui.control.NumericEditField
        NextButton                 matlab.ui.control.Button
        ClassesDropDownLabel       matlab.ui.control.Label
        MovClassesDropDown         matlab.ui.control.DropDown
        FileNameEditFieldLabel     matlab.ui.control.Label
        FileNameEditField          matlab.ui.control.EditField
        StartRecordingButton       matlab.ui.control.StateButton
        TrajectoriesDropDownLabel  matlab.ui.control.Label
        TrajectoriesDropDown       matlab.ui.control.DropDown
        LoadRestDataButton         matlab.ui.control.StateButton
        ConnectMyoButton           matlab.ui.control.StateButton
    end


    properties (Access = private)
        mmex 
        mdata
        emgTimer                %Timer, der styrer hvor tit vi ser på timerfuktionen
        Itraj = 1
        Imov = 1
        i                       % variable til optælling af antalt rms signaler at lave MAV over
        movMVC
        calibratingMVC
        EMG_TIMER_PERIOD = 0.15 %Opdateringsfrekvevns (perioden for timeren)
        Nwin                    %Antal af samples i hvert vindue
        cursor                  %Røde cirkel
        arr = []                % opretter array til at gemme de seneste rmsEMG samples i
        restArr = []            % Rest array til alle rest værdierne
        
        ItemsForDropdownMenu = {'Flexion','Extension','Radial_deviation','Ulnar_deviation','Open','Close'}
        ItemsForDropdownMenuStart = {'Rest'}
        RealItemsForDropdown = {}
        RandomItemForDropdown = {}
        
        
        Restdata          %data brugt til at loade vores rest data
        

    end
    
    methods (Access = private)
        
        function UpdateFileName( app )
            app.FileNameEditField.Value = [app.MovClassesDropDown.Value '_'  num2str( CollectTrainingData.GetSelectedIndex( app.TrajectoriesDropDown ) ) ];
        end 
        
        function UpdateTrajectory( app )
            traj = str2num( app.TrajectoriesDropDown.Value );
            hold( app.EMGAxes, 'off' );
            plot( app.EMGAxes, traj( :, 1 ), traj( :, 2 ), 'LineWidth', 2 );
            ylim( app.EMGAxes, [ 0 1 ] );
            hold( app.EMGAxes, 'on' );
            app.cursor = plot( app.EMGAxes, 0, 0, 'o','MarkerFaceColor','r', 'MarkerSize', 10 ); 
        end
        
        function UpdateMVC( app )
            idx = CollectTrainingData.GetSelectedIndex( app.MovClassesDropDown );
            app.MVCEditField.Value = app.movMVC( idx );
        end
    
        function LoadData ( app )
            %idx = CollectTrainingData.GetSelectedIndex( app.MovClassesDropDown );
            %if (CollectTrainingData.GetSelectedIndex( app.MovClassesDropDown ) == 1)
            %app.restArr = app.Restdata.emg.values;
            %end
        end
    
        function ReadEMG( app, source, ~ )
            N = min(length( app.mdata.emg_log ), app.Nwin); 
            if N == 0        
                app.i = 1;  % sætter i til 1 så længe vi ikke optager signalet
                return, 
            end
            
                rmsEMG = rms( app.mdata.emg_log( end:-1:( end - N ) ) ); 
             
            try
                %% if/else to save and subtract 'rest' from the remaining signals
                %% vv
                if app.CalibrateMVCButton.Value == false
                    if (CollectTrainingData.GetSelectedIndex( app.MovClassesDropDown ) == 1)
                    app.restArr(1:length(app.mdata.emg_log),CollectTrainingData.GetSelectedIndex( app.TrajectoriesDropDown )*8-7:CollectTrainingData.GetSelectedIndex( app.TrajectoriesDropDown )*8) = app.mdata.emg_log;
                    else
                    rmsEMG = max(rmsEMG-rms(rms(app.restArr)), 0);
                    end
                else 
                end 
                
                %% ^^
            catch ME
	    	message = sprintf('Error in rest subtraction:\n%s', ME.message);
	    	uiwait(warndlg(message));
            end
            
     
            try
                %% Saves X rmsEMG in moving array as part of the MAV to smoothing of the displayed signal.
                %% vv
                app.arr(app.i) = rmsEMG;
                if app.i == 5           %Antal rms signaler MAV laves over
                    app.i = 1;
                else
                    app.i = app.i+1;
                end
                %% ^^
            catch ME
		message = sprintf('Error in MAV calculation:\n%s', ME.message);
		uiwait(warndlg(message));
            end
                
                
            idx = CollectTrainingData.GetSelectedIndex( app.MovClassesDropDown );
            if app.calibratingMVC
                app.movMVC( idx ) = max( rmsEMG, app.movMVC( idx ) );
                app.UpdateMVC;
            else
                t = get( source, 'TasksExecuted' ) * app.EMG_TIMER_PERIOD;
                set( app.cursor, 'XData', t );
                set( app.cursor, 'YData', mean(app.arr) / app.movMVC( idx ) );
            end 
        end 
    end

    methods (Static)
        function idx = GetSelectedIndex( dd )
            for i = 1:length( dd.Items )
                if strcmp( dd.Items{ i }, dd.Value )
                    idx = i;
                    return
                end 
            end 
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('ST7\MyoWrapper\')
            app.RandomItemForDropdown = app.ItemsForDropdownMenu(randperm(length(app.ItemsForDropdownMenu)));
            app.RealItemsForDropdown = [app.ItemsForDropdownMenuStart app.RandomItemForDropdown];
            app.MovClassesDropDown.Items = app.RealItemsForDropdown;
            app.emgTimer = timer( 'Busy', 'drop', 'ExecutionMode', 'fixedRate', 'TimerFcn', @app.ReadEMG, 'Period', app.EMG_TIMER_PERIOD ); %Timerfunktionen sættes til at pege på @app.ReadEMG. 
            app.UpdateFileName;
            app.UpdateTrajectory;
            app.movMVC = zeros( 1, length( app.MovClassesDropDown.Items ) );
            app.UpdateMVC;
            app.calibratingMVC = false;
            app.Nwin = app.EMG_TIMER_PERIOD * 200;
          
        end

        % Value changed function: CalibrateMVCButton
        function CalibrateMVCButtonValueChanged(app, event)
            if app.CalibrateMVCButton.Value
                app.calibratingMVC = true;
                app.MVCEditField.Value = 0;
                idx = CollectTrainingData.GetSelectedIndex( app.MovClassesDropDown );
                app.movMVC(idx) = 0;
                app.mdata.clearLogs;
                app.mdata.startStreaming;
                start( app.emgTimer );
            else
                app.calibratingMVC = false;
                app.mdata.stopStreaming;
                stop( app.emgTimer );
            end
        end

        % Close request function: CollectTrainingDataFigure
        function CollectTrainingDataFigureCloseRequest(app, event)
            delete( app.emgTimer );
            delete(app);
        end

        % Value changed function: ConnectMyoButton
        function ConnectMyoButtonValueChanged(app, event)
            if app.ConnectMyoButton.Value
                try
                    app.mmex = MyoMex( 1 );
                    app.mdata = app.mmex.myoData( 1 );
                    app.MyoLamp.Color = 'g';
                catch ME
                    msgbox( ME.message );
                    app.ConnectMyoButton.Value = 0;
                end
            else
                app.MyoLamp.Color = 'k';
                delete( app.mmex );
            end
        end

        % Value changed function: MovClassesDropDown
        function MovClassesDropDownValueChanged(app, event)
            app.UpdateFileName;
            app.UpdateMVC;
            app.Imov = CollectTrainingData.GetSelectedIndex(app.MovClassesDropDown);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.Itraj < length( app.TrajectoriesDropDown.Items )
                app.Itraj = app.Itraj + 1;
            else
                app.Itraj = 1;
                if app.Imov < length( app.MovClassesDropDown.Items )
                    app.Imov = app.Imov + 1;
                else
                    app.Imov = 1;
                end
            end 
            app.MovClassesDropDown.Value = app.MovClassesDropDown.Items{ app.Imov };
            app.TrajectoriesDropDown.Value = app.TrajectoriesDropDown.Items{ app.Itraj };
            app.UpdateTrajectory;
            app.UpdateFileName;
            app.UpdateMVC;
            app.LoadRestDataButton.Value = false;
        end

        % Value changed function: StartRecordingButton
        function StartRecordingButtonValueChanged(app, event)
            value = app.StartRecordingButton.Value;
            if value
                app.mdata.clearLogs;
                app.mdata.startStreaming;
                start( app.emgTimer );
            else
                app.mdata.stopStreaming;                
                cla( app.EMGAxes );
                stop( app.emgTimer );
                emg.values = app.mdata.emg_log(1:4000,:);
                emg.time = app.mdata.timeEMG_log(1:4000);
                emg.MVC = app.MVCEditField.Value;
                save( app.FileNameEditField.Value, 'emg'); %Her navngiver vi den struct vi får inde i vores .mat-fil "emg" 
                
            end 
        end

        % Value changed function: TrajectoriesDropDown
        function TrajectoriesDropDownValueChanged(app, event)
            app.UpdateFileName;
            app.UpdateTrajectory;
            app.Itraj = CollectTrainingData.GetSelectedIndex( app.TrajectoriesDropDown );
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            
        end

        % Value changed function: MVCEditField
        function MVCEditFieldValueChanged(app, event)
            value = app.MVCEditField.Value;
            
        end

        % Value changed function: LoadRestDataButton
        function LoadRestDataButtonValueChanged(app, event)
            value = app.LoadRestDataButton.Value;
            app.Restdata = load(uigetfile);
            app.CollectTrainingDataFigure.Visible = 'off'; %brugt for at få Appen til at komme frem igen
            app.CollectTrainingDataFigure.Visible = 'on';  
            app.LoadData;
            emg.values = app.Restdata.emg.values;
            emg.time = app.Restdata.emg.time;
            emg.MVC = app.Restdata.emg.MVC;
            app.MVCEditField.Value = emg.MVC;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CollectTrainingDataFigure = uifigure;
            app.CollectTrainingDataFigure.Name = 'Collect Training Data';
            app.CollectTrainingDataFigure.CloseRequestFcn = createCallbackFcn(app, @CollectTrainingDataFigureCloseRequest, true);
            app.EMGAxes = uiaxes(app.CollectTrainingDataFigure);
            title(app.EMGAxes, 'Track the reference trajectory')
            xlabel(app.EMGAxes, 'time (s)')
            ylabel(app.EMGAxes, 'normalized amplitude')
            app.EMGAxes.XLim = [0 20];
            app.EMGAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.EMGAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.MyoLamp = uilamp(app.CollectTrainingDataFigure);
            app.RecordingProtocolPanel = uipanel(app.CollectTrainingDataFigure);
            app.RecordingProtocolPanel.Title = 'Recording Protocol';
            app.CalibrateMVCButton = uibutton(app.RecordingProtocolPanel, 'state');
            app.CalibrateMVCButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateMVCButtonValueChanged, true);
            app.CalibrateMVCButton.Text = 'Calibrate MVC';
            app.MVCEditField = uieditfield(app.RecordingProtocolPanel, 'numeric');
            app.MVCEditField.ValueChangedFcn = createCallbackFcn(app, @MVCEditFieldValueChanged, true);
            app.NextButton = uibutton(app.RecordingProtocolPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ClassesDropDownLabel = uilabel(app.RecordingProtocolPanel);
            app.ClassesDropDownLabel.Text = 'Classes';
            app.MovClassesDropDown = uidropdown(app.RecordingProtocolPanel);
            app.MovClassesDropDown.Items = {'Rest', 'Flexion', 'Extension', 'Radial_deviation', 'Ulnar_deviation', 'Supination', 'Pronation'};
            app.MovClassesDropDown.ValueChangedFcn = createCallbackFcn(app, @MovClassesDropDownValueChanged, true);
            app.MovClassesDropDown.Value = 'Rest';
            app.FileNameEditFieldLabel = uilabel(app.RecordingProtocolPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RecordingProtocolPanel, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.StartRecordingButton = uibutton(app.RecordingProtocolPanel, 'state');
            app.StartRecordingButton.ValueChangedFcn = createCallbackFcn(app, @StartRecordingButtonValueChanged, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.TrajectoriesDropDownLabel = uilabel(app.RecordingProtocolPanel);
            app.TrajectoriesDropDownLabel.Text = 'Trajectories';
            app.TrajectoriesDropDown = uidropdown(app.RecordingProtocolPanel);
            app.TrajectoriesDropDown.Items = {'[0 0; 4 0; 6 0.25; 14 0.25; 16 0; 20 0]', '[0 0; 4 0; 6 0.50; 14 0.50; 16 0; 20 0]', '[0 0; 4 0; 6 0.75; 14 0.75; 16 0; 20 0]'};
            app.TrajectoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesDropDownValueChanged, true);
            app.TrajectoriesDropDown.Value = '[0 0; 4 0; 6 0.25; 14 0.25; 16 0; 20 0]';
            app.LoadRestDataButton = uibutton(app.RecordingProtocolPanel, 'state');
            app.LoadRestDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadRestDataButtonValueChanged, true);
            app.LoadRestDataButton.Text = 'Load Rest Data';
            app.ConnectMyoButton = uibutton(app.CollectTrainingDataFigure, 'state');
            app.ConnectMyoButton.ValueChangedFcn = createCallbackFcn(app, @ConnectMyoButtonValueChanged, true);
            app.ConnectMyoButton.Text = 'Connect Myo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CollectTrainingData

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CollectTrainingDataFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CollectTrainingDataFigure)
        end
    end
end